classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_10                        matlab.ui.control.Label
        NameofpatientstudyLabel         matlab.ui.control.Label
        MaxPatientIDEditField           matlab.ui.control.NumericEditField
        MaxPatientIDEditFieldLabel      matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        infoButton_11                   matlab.ui.control.Button
        infoButton_10                   matlab.ui.control.Button
        SETFOLDERREQUIREMENTSLabel      matlab.ui.control.Label
        SETPATIENTINFORMATIONLabel      matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        infoButton_9                    matlab.ui.control.Button
        infoButton_8                    matlab.ui.control.Button
        infoButton_7                    matlab.ui.control.Button
        infoButton_6                    matlab.ui.control.Button
        infoButton_5                    matlab.ui.control.Button
        infoButton_4                    matlab.ui.control.Button
        infoButton_3                    matlab.ui.control.Button
        infoButton_2                    matlab.ui.control.Button
        infoButton                      matlab.ui.control.Button
        FibresformeshButton             matlab.ui.control.Button
        CheckBoxtosavegrowthtensordatatoexcelfile  matlab.ui.control.CheckBox
        CheckboxtosavevolumedatatoexcelfileCheckBox  matlab.ui.control.CheckBox
        PathtoexcelfilewhichwillsavedataEditField  matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        LVmeshgeneratorLabel            matlab.ui.control.Label
        FollowupDropDown                matlab.ui.control.DropDown
        FollowupDropDownLabel           matlab.ui.control.Label
        BaselineDropDown                matlab.ui.control.DropDown
        BaselineDropDownLabel           matlab.ui.control.Label
        ComputegrowthtensorButton       matlab.ui.control.Button
        AbaqusfilepreparationButton     matlab.ui.control.Button
        POSTPROCESSINGSTEPSLabel        matlab.ui.control.Label
        MESHGENERATIONSTEPSLabel        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NoofimagesDropDown              matlab.ui.control.DropDown
        NoofimagesDropDownLabel         matlab.ui.control.Label
        InputrootwhereMATLABprogrammesaresavedEditField  matlab.ui.control.EditField
        InputrootwhereMATLABprogrammesaresavedEditFieldLabel  matlab.ui.control.Label
        LastSAsliceEditField            matlab.ui.control.NumericEditField
        LastSAsliceEditFieldLabel       matlab.ui.control.Label
        EarlydiastoleimageEditField     matlab.ui.control.NumericEditField
        EarlydiastoleimageEditFieldLabel  matlab.ui.control.Label
        EnddiastoleimageEditField       matlab.ui.control.NumericEditField
        EnddiastoleimageEditFieldLabel  matlab.ui.control.Label
        EndsystoleimageEditField        matlab.ui.control.NumericEditField
        EndsystoleimageEditFieldLabel   matlab.ui.control.Label
        FirstSAsliceEditField           matlab.ui.control.NumericEditField
        FirstSAsliceEditFieldLabel      matlab.ui.control.Label
        InputfolderwhereallMRIimagesarestoredEditField  matlab.ui.control.EditField
        InputfolderwhereallMRIimagesarestoredEditFieldLabel  matlab.ui.control.Label
        ScanTimeDropDown                matlab.ui.control.DropDown
        ScanTimeDropDownLabel           matlab.ui.control.Label
        LoaddataButton                  matlab.ui.control.Button
        GenerateMeshButton              matlab.ui.control.Button
        PatientIDEditField              matlab.ui.control.NumericEditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        SAadjustmentrun3timesButton     matlab.ui.control.Button
        PickapexpointsButton            matlab.ui.control.Button
        LAsegmentationButton            matlab.ui.control.Button
        DiastoletimeDropDown            matlab.ui.control.DropDown
        DiastoletimeDropDownLabel       matlab.ui.control.Label
        SAsegmentationButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            
           global total_number_of_SA_images;
           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_SA_slices;
           global period_time_str;

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

CF = isempty(root_folder);

if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end

if max_patient_id == 0
error('Max patient ID has not been entered.');
end

SF = isempty(max_patient_id);

if SF == 1
error('Max patient ID has not been entered.');
end


           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;


           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


           name_of_study = app.EditField.Value;


SSF = isempty(name_of_study);

if SSF == 1
error('Name of study has not been entered.');
end

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);

           cd(folder_second);

           if ~exist(third_directory_name,'dir')
           mkdir(third_directory_name)
           end

           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)


            LVWM_DicomSampleSelection_scott


        end

        % Button pushed function: SAsegmentationButton
        function SAsegmentationButtonPushed(app, event)

           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;

           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;

CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


           TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end


           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


           if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);


           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)

            All_SA_ManualSegmentation_scott
        end

        % Button pushed function: LAsegmentationButton
        function LAsegmentationButtonPushed(app, event)

           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;

           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;

CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end


           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;


           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);


           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)


            All_LA_ManualSegmentation_scott
        end

        % Button pushed function: PickapexpointsButton
        function PickapexpointsButtonPushed(app, event)

           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;

           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;


CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


           TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


           if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);


           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)


            LVWM_Apex_LASegManual_scott
        end

        % Button pushed function: SAadjustmentrun3timesButton
        function SAadjustmentrun3timesButtonPushed(app, event)

           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;

           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;

CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


           TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);

           cd(folder_second);

           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)

            SAAdjustmentBYLASeries_scott
        end

        % Button pushed function: GenerateMeshButton
        function GenerateMeshButtonPushed(app, event)


           global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;
           global yes_or_no_checkbox;
           global working_letter;
           global where_to_save_excel;


           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;
           where_to_save_excel=app.PathtoexcelfilewhichwillsavedataEditField.Value;

CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end


DF = isempty(where_to_save_excel); 

if DF == 1
    where_to_save_excel=root_folder;
end


           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           yes_or_no_checkbox = app.CheckboxtosavevolumedatatoexcelfileCheckBox.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;


           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);

           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)

            All_fitting_reconstruction_scott


if yes_or_no_checkbox == true
"successfully constructed mesh, volume data displayed in MATLAB window and added to excel file"
else
"successfully constructed mesh, volume data displayed in MATLAB window "
end

        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            
        end

        % Value changed function: 
        % InputfolderwhereallMRIimagesarestoredEditField
        function InputfolderwhereallMRIimagesarestoredEditFieldValueChanged(app, event)
            value = app.InputfolderwhereallMRIimagesarestoredEditField.Value;
        end

        % Value changed function: 
        % InputrootwhereMATLABprogrammesaresavedEditField
        function InputrootwhereMATLABprogrammesaresavedEditFieldValueChanged(app, event)
            value = app.InputrootwhereMATLABprogrammesaresavedEditField.Value;
 
        end

        % Value changed function: NoofimagesDropDown
        function NoofimagesDropDownValueChanged(app, event)
            value = app.NoofimagesDropDown.Value;
        end

        % Value changed function: 
        % CheckboxtosavevolumedatatoexcelfileCheckBox
        function CheckboxtosavevolumedatatoexcelfileCheckBoxValueChanged(app, event)
            value = app.CheckboxtosavevolumedatatoexcelfileCheckBox.Value;
            
        end

        % Value changed function: PathtoexcelfilewhichwillsavedataEditField
        function PathtoexcelfilewhichwillsavedataEditFieldValueChanged(app, event)
            value = app.PathtoexcelfilewhichwillsavedataEditField.Value;
            
        end

        % Button pushed function: AbaqusfilepreparationButton
        function AbaqusfilepreparationButtonPushed(app, event)
            

             global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;
           global yes_or_no_checkbox;
           global working_letter;
           global where_to_save_excel;


           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;
           where_to_save_excel=app.PathtoexcelfilewhichwillsavedataEditField.Value;


CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


           TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           yes_or_no_checkbox = app.CheckboxtosavevolumedatatoexcelfileCheckBox.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);

           folder_results_final=fullfile(folder_second,third_directory_name);

           cd(matlab_programme_folder_str)

global abaqusInputDir_input;
global dataResultinput;
global abaqusDirinput;

abaqusInputDir_input=append(folder_results_final,'\fibreGeneration');
dataResultinput=append(abaqusInputDir_input,'\Results_fiber_60_45');
abaqusDirinput=append(folder_results_final,'\abaqusSimulation');

            ABAQUS_FilePreparation_scott          


        end

        % Button pushed function: FibresformeshButton
        function FibresformeshButtonPushed(app, event)
            
             global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;
           global yes_or_no_checkbox;
           global working_letter;
           global where_to_save_excel;


           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;
           where_to_save_excel=app.PathtoexcelfilewhichwillsavedataEditField.Value;


CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end

           total_number_of_SA_images=app.NoofimagesDropDown.Value;
           early_diastole_time_for_config_file=app.EarlydiastoleimageEditField.Value;
           end_diastole_time_for_config_file=app.EnddiastoleimageEditField.Value;
           end_systole_time_for_config_file=app.EndsystoleimageEditField.Value;

           yes_or_no_checkbox = app.CheckboxtosavevolumedatatoexcelfileCheckBox.Value;

           first_sa_slice_for_config_file=app.FirstSAsliceEditField.Value;
           last_sa_slice_for_config_file=app.LastSAsliceEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

if early_diastole_time_for_config_file == 0
error('Early diastole image number cannot be 0');
end

if end_diastole_time_for_config_file == 0
error('End diastole image number cannot be 0');
end

if end_systole_time_for_config_file == 0
error('End systole image number cannot be 0');
end

if last_sa_slice_for_config_file == 0
error('The final Short Axis slice cannot be 0');
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           letter_str = string(patient_letter);
           root_folder_str = string(root_folder);
           period_time_str = string(period_time);
           matlab_programme_folder_str=string(matlab_programme_folder);


first_sa_slice_for_config_file_str = string(first_sa_slice_for_config_file);
last_sa_slice_for_config_file_str = string(last_sa_slice_for_config_file);

first_sa_slice_composed=append('SA',first_sa_slice_for_config_file_str);
first_sa_slice_studyName=append('SA-plane-',first_sa_slice_for_config_file_str);

total_SA_slices=last_sa_slice_for_config_file-first_sa_slice_for_config_file+1;

           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           working_letter=append(first_letter,letter_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           working_letter=append(first_letter,letter_str);                            
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2

                           
           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           working_letter=append(first_letter,letter_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1

                           
           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           working_letter=append(patient_str,letter_str);
           else
           "Patient ID should have max 1 digit";
           end


end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);

           full_dicom_directory_computed=fullfile(folder_second,working_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name=append(current_patient,letter_str);
           second_directory_name=append(first_directory_name,'_results_');
           third_directory_name=append(second_directory_name,directory_time);

           folder_results_final=fullfile(folder_second,third_directory_name);

           fibre_folder_name=fullfile(matlab_programme_folder_str,'HexMeshProcessing_not_using');

           cd(fibre_folder_name);

global abaqusInputDir_input;
global dataResultinput;
global abaqusDirinput;
global folder_results_final_to_use;

abaqusInputDir_input=fullfile(folder_results_final,'fibreGeneration');
dataResultinput=fullfile(abaqusInputDir_input,'Results_fiber_60_45');
abaqusDirinput=fullfile(folder_results_final,'abaqusSimulation');
folder_results_final_to_use=folder_results_final;

          FibreForHexLV_scott


        end

        % Button pushed function: ComputegrowthtensorButton
        function ComputegrowthtensorButtonPushed(app, event)
            
%growth tensor calculation

             global first_sa_slice_for_config_file;
           global early_diastole_time_for_config_file;
           global end_diastole_time_for_config_file;
           global end_systole_time_for_config_file;
           global first_directory_name;
           global full_dicom_directory_computed;
           global folder_results_final;
           global matlab_programme_folder_str;
           global total_number_of_SA_images;
           global total_SA_slices;
           global period_time_str;
           global yes_or_no_checkbox;
           global working_letter;
           global where_to_save_excel;
           global yes_or_no_checkbox_growth;
           global current_patient;
           global folder_second;

baseline_id_for_calculation=app.BaselineDropDown.Value;
follow_id_for_calculation=app.FollowupDropDown.Value;


if baseline_id_for_calculation == follow_id_for_calculation
error('Baeline and follow up times are set the same, update this and run again')
end


baseline_id_for_calculation_str = string(baseline_id_for_calculation);
follow_id_for_calculation_str = string(follow_id_for_calculation);


           yes_or_no_checkbox_growth = app.CheckBoxtosavegrowthtensordatatoexcelfile.Value;


           patient_number=app.PatientIDEditField.Value;
           patient_letter=app.ScanTimeDropDown.Value;
           period_time=app.DiastoletimeDropDown.Value;
           where_to_save_excel=app.PathtoexcelfilewhichwillsavedataEditField.Value;

           root_folder=app.InputfolderwhereallMRIimagesarestoredEditField.Value;
           matlab_programme_folder=app.InputrootwhereMATLABprogrammesaresavedEditField.Value;

           max_patient_id = app.MaxPatientIDEditField.Value;
           max_patient_id_string=string(max_patient_id);
           max_patient_id_string_length = strlength(max_patient_id_string);

CF = isempty(root_folder);
if CF == 1
error('no input folder for MRI images has been entered, input this directory');
end


TF = isempty(matlab_programme_folder); 

if TF == 1
s=which('App.mlapp');
matlab_programme_folder=s(1:end-9);
end


DF = isempty(where_to_save_excel); 

if DF == 1
    where_to_save_excel=root_folder;
end

           patient_str = string(patient_number);
           patient_str_length = strlength(patient_str);
           period_time_str = string(period_time);
           letter_str = string(patient_letter);


           root_folder_str = string(root_folder);
           matlab_programme_folder_str=string(matlab_programme_folder);


           name_of_study = app.EditField.Value;

           name_of_study_str=string(name_of_study);
           name_of_study_str_single_zero=append(name_of_study_str,'0');
           name_of_study_str_double_zero=append(name_of_study_str,'00');
           name_of_study_str_triple_zero=append(name_of_study_str,'000');


if max_patient_id_string_length==4


           if patient_str_length==1
           current_patient=append(name_of_study_str_triple_zero,patient_str);
           first_letter=append('000',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);  
           elseif patient_str_length==3
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);
           elseif patient_str_length==4    
           current_patient=append(name_of_study_str,patient_str);
           baseline_letter=append(patient_str,baseline_id_for_calculation_str);
           follow_letter=append(patient_str,follow_id_for_calculation_str);
           else
           "Patient ID should have max 4 digits";
           end

elseif max_patient_id_string_length==3

           if patient_str_length==1
           current_patient=append(name_of_study_str_double_zero,patient_str);
           first_letter=append('00',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);  
           elseif patient_str_length==2
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);
           elseif patient_str_length==3    
           current_patient=append(name_of_study_str,patient_str);
           baseline_letter=append(patient_str,baseline_id_for_calculation_str);
           follow_letter=append(patient_str,follow_id_for_calculation_str);
           else
           "Patient ID should have max 3 digits";
           end


elseif max_patient_id_string_length==2


           if patient_str_length==1
           current_patient=append(name_of_study_str_single_zero,patient_str);
           first_letter=append('0',patient_str);
           baseline_letter=append(first_letter,baseline_id_for_calculation_str);
           follow_letter=append(first_letter,follow_id_for_calculation_str);
           elseif patient_str_length==2    
           current_patient=append(name_of_study_str,patient_str);
           baseline_letter=append(patient_str,baseline_id_for_calculation_str);
           follow_letter=append(patient_str,follow_id_for_calculation_str);
           else
           "Patient ID should have max 2 digits";
           end


elseif max_patient_id_string_length==1


           if patient_str_length==1    
           current_patient=append(name_of_study_str,patient_str);
           baseline_letter=append(patient_str,baseline_id_for_calculation_str);
           follow_letter=append(patient_str,follow_id_for_calculation_str);
           else
           "Patient ID should have max 1 digit";
           end

end


           folder_first= root_folder_str; 
           folder_second = fullfile(folder_first, current_patient);


           %full_dicom_directory_first=append(folder_second,'\');

           full_dicom_directory_computed_baseline=fullfile(folder_second,baseline_letter);
           full_dicom_directory_computed_follow=fullfile(folder_second,follow_letter);

           if period_time_str == 'Early diastole'
             directory_time='Early_diastole';
           elseif period_time_str == 'End diastole'
             directory_time='End_diastole';
           end

           first_directory_name_base=append(current_patient,baseline_id_for_calculation_str);
           first_directory_name_follow=append(current_patient,follow_id_for_calculation_str);

  
           second_directory_name_base=append(first_directory_name_base,'_results_');
           second_directory_name_follow=append(first_directory_name_follow,'_results_');
           second_directory_name_saver=append(current_patient,'_growth_tensor_plot_results_');

           third_directory_name_base=append(second_directory_name_base,directory_time);
           third_directory_name_follow=append(second_directory_name_follow,directory_time);
           third_directory_name_saver=append(second_directory_name_saver,directory_time);

           global folder_results_final_baseline;
           global folder_results_final_follow;
           global folder_results_final_saver;

           folder_results_final_baseline=fullfile(folder_second,third_directory_name_base);
           folder_results_final_follow=fullfile(folder_second,third_directory_name_follow);
           folder_results_final_saver=fullfile(folder_second,third_directory_name_saver);


cd(matlab_programme_folder_str)

automating_procedure_scott

if yes_or_no_checkbox_growth == true
"successfully computed growth tensor, results displayed in MATLAB and added to excel file"
else
"successfully computed growth tensor, results displayed in MATLAB"
end


        end

        % Value changed function: BaselineDropDown
        function BaselineDropDownValueChanged(app, event)
            value = app.BaselineDropDown.Value;
        end

        % Value changed function: FollowupDropDown
        function FollowupDropDownValueChanged(app, event)
            value = app.FollowupDropDown.Value;            
        end

        % Value changed function: CheckBoxtosavegrowthtensordatatoexcelfile
        function CheckBoxtosavegrowthtensordatatoexcelfileValueChanged(app, event)
            value = app.CheckBoxtosavegrowthtensordatatoexcelfile.Value;
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            
s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'load_data.txt');

open(ssss);

        end

        % Button pushed function: infoButton_2
        function infoButton_2Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'SA_segment.txt');

open(ssss);


        end

        % Button pushed function: infoButton_3
        function infoButton_3Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'LA_segment.txt');

open(ssss);


        end

        % Button pushed function: infoButton_4
        function infoButton_4Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'pick_apex_points.txt');

open(ssss);


        end

        % Button pushed function: infoButton_5
        function infoButton_5Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'SA_adjustment.txt');

open(ssss);


        end

        % Button pushed function: infoButton_9
        function infoButton_9Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'generate_mesh.txt');

open(ssss);


        end

        % Button pushed function: infoButton_6
        function infoButton_6Pushed(app, event)
            

s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'abaqus_file_prep.txt');

open(ssss);


        end

        % Button pushed function: infoButton_7
        function infoButton_7Pushed(app, event)
            
s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'fibres_for_mesh.txt');

open(ssss);


        end

        % Button pushed function: infoButton_8
        function infoButton_8Pushed(app, event)
            
s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'growth_tensor.txt');

open(ssss);


        end

        % Button pushed function: infoButton_11
        function infoButton_11Pushed(app, event)
    
s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'set_patient_information.txt');

open(ssss);


        end

        % Button pushed function: infoButton_10
        function infoButton_10Pushed(app, event)


s=which('App.mlapp');
ss=s(1:end-9);
sss=fullfile(ss,'information_folder');
ssss=fullfile(sss,'set_folder_requirements.txt');

open(ssss);


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;    
        end

        % Value changed function: MaxPatientIDEditField
        function MaxPatientIDEditFieldValueChanged(app, event)
            value = app.MaxPatientIDEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SAsegmentationButton = uibutton(app.UIFigure, 'push');
            app.SAsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SAsegmentationButtonPushed, true);
            app.SAsegmentationButton.Text = 'SA segmentation';
            app.DiastoletimeDropDownLabel = uilabel(app.UIFigure);
            app.DiastoletimeDropDownLabel.Text = 'Diastole time';
            app.DiastoletimeDropDown = uidropdown(app.UIFigure);
            app.DiastoletimeDropDown.Items = {'Early diastole', 'End diastole'};
            app.DiastoletimeDropDown.Value = 'Early diastole';
            app.LAsegmentationButton = uibutton(app.UIFigure, 'push');
            app.LAsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @LAsegmentationButtonPushed, true);
            app.LAsegmentationButton.Text = 'LA segmentation';
            app.PickapexpointsButton = uibutton(app.UIFigure, 'push');
            app.PickapexpointsButton.ButtonPushedFcn = createCallbackFcn(app, @PickapexpointsButtonPushed, true);
            app.PickapexpointsButton.Text = 'Pick apex points';
            app.SAadjustmentrun3timesButton = uibutton(app.UIFigure, 'push');
            app.SAadjustmentrun3timesButton.ButtonPushedFcn = createCallbackFcn(app, @SAadjustmentrun3timesButtonPushed, true);
            app.SAadjustmentrun3timesButton.Text = {'SA adjustment'; '(run 3 times)'};
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.GenerateMeshButton = uibutton(app.UIFigure, 'push');
            app.GenerateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshButtonPushed, true);
            app.GenerateMeshButton.Text = 'Generate Mesh';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ScanTimeDropDownLabel = uilabel(app.UIFigure);
            app.ScanTimeDropDownLabel.Text = 'Scan Time';
            app.ScanTimeDropDown = uidropdown(app.UIFigure);
            app.ScanTimeDropDown.Items = {'a', 'b', 'c'};
            app.ScanTimeDropDown.Value = 'a';
            app.InputfolderwhereallMRIimagesarestoredEditFieldLabel = uilabel(app.UIFigure);
            app.InputfolderwhereallMRIimagesarestoredEditFieldLabel.Text = 'Input folder where all MRI images are stored ';
            app.InputfolderwhereallMRIimagesarestoredEditField = uieditfield(app.UIFigure, 'text');
            app.InputfolderwhereallMRIimagesarestoredEditField.ValueChangedFcn = createCallbackFcn(app, @InputfolderwhereallMRIimagesarestoredEditFieldValueChanged, true);
            app.InputfolderwhereallMRIimagesarestoredEditField.Placeholder = 'Results will be saved in the same directory';
            app.FirstSAsliceEditFieldLabel = uilabel(app.UIFigure);
            app.FirstSAsliceEditFieldLabel.Text = 'First SA slice';
            app.FirstSAsliceEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndsystoleimageEditFieldLabel = uilabel(app.UIFigure);
            app.EndsystoleimageEditFieldLabel.Text = 'End systole image';
            app.EndsystoleimageEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnddiastoleimageEditFieldLabel = uilabel(app.UIFigure);
            app.EnddiastoleimageEditFieldLabel.Text = 'End diastole image';
            app.EnddiastoleimageEditField = uieditfield(app.UIFigure, 'numeric');
            app.EarlydiastoleimageEditFieldLabel = uilabel(app.UIFigure);
            app.EarlydiastoleimageEditFieldLabel.Text = 'Early diastole image';
            app.EarlydiastoleimageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LastSAsliceEditFieldLabel = uilabel(app.UIFigure);
            app.LastSAsliceEditFieldLabel.Text = 'Last SA slice';
            app.LastSAsliceEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputrootwhereMATLABprogrammesaresavedEditFieldLabel = uilabel(app.UIFigure);
            app.InputrootwhereMATLABprogrammesaresavedEditFieldLabel.Text = 'Input root where MATLAB programmes are saved';
            app.InputrootwhereMATLABprogrammesaresavedEditField = uieditfield(app.UIFigure, 'text');
            app.InputrootwhereMATLABprogrammesaresavedEditField.ValueChangedFcn = createCallbackFcn(app, @InputrootwhereMATLABprogrammesaresavedEditFieldValueChanged, true);
            app.InputrootwhereMATLABprogrammesaresavedEditField.Placeholder = 'This can be left blank if directory is same as main programme';
            app.NoofimagesDropDownLabel = uilabel(app.UIFigure);
            app.NoofimagesDropDownLabel.Text = 'No. of images';
            app.NoofimagesDropDown = uidropdown(app.UIFigure);
            app.NoofimagesDropDown.Items = {'20', '25'};
            app.NoofimagesDropDown.ValueChangedFcn = createCallbackFcn(app, @NoofimagesDropDownValueChanged, true);
            app.NoofimagesDropDown.Value = '20';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.MESHGENERATIONSTEPSLabel = uilabel(app.UIFigure);
            app.MESHGENERATIONSTEPSLabel.Text = 'MESH GENERATION STEPS';
            app.POSTPROCESSINGSTEPSLabel = uilabel(app.UIFigure);
            app.POSTPROCESSINGSTEPSLabel.Text = 'POST PROCESSING STEPS';
            app.AbaqusfilepreparationButton = uibutton(app.UIFigure, 'push');
            app.AbaqusfilepreparationButton.ButtonPushedFcn = createCallbackFcn(app, @AbaqusfilepreparationButtonPushed, true);
            app.AbaqusfilepreparationButton.Text = 'Abaqus file preparation';
            app.ComputegrowthtensorButton = uibutton(app.UIFigure, 'push');
            app.ComputegrowthtensorButton.ButtonPushedFcn = createCallbackFcn(app, @ComputegrowthtensorButtonPushed, true);
            app.ComputegrowthtensorButton.Text = 'Compute growth tensor';
            app.BaselineDropDownLabel = uilabel(app.UIFigure);
            app.BaselineDropDownLabel.Text = 'Baseline';
            app.BaselineDropDown = uidropdown(app.UIFigure);
            app.BaselineDropDown.Items = {'a', 'b', 'c'};
            app.BaselineDropDown.ValueChangedFcn = createCallbackFcn(app, @BaselineDropDownValueChanged, true);
            app.BaselineDropDown.Value = 'a';
            app.FollowupDropDownLabel = uilabel(app.UIFigure);
            app.FollowupDropDownLabel.Text = 'Follow up';
            app.FollowupDropDown = uidropdown(app.UIFigure);
            app.FollowupDropDown.Items = {'a', 'b', 'c'};
            app.FollowupDropDown.ValueChangedFcn = createCallbackFcn(app, @FollowupDropDownValueChanged, true);
            app.FollowupDropDown.Value = 'a';
            app.LVmeshgeneratorLabel = uilabel(app.UIFigure);
            app.LVmeshgeneratorLabel.Text = 'LV mesh generator';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Path to excel file which will save data';
            app.PathtoexcelfilewhichwillsavedataEditField = uieditfield(app.UIFigure, 'text');
            app.PathtoexcelfilewhichwillsavedataEditField.ValueChangedFcn = createCallbackFcn(app, @PathtoexcelfilewhichwillsavedataEditFieldValueChanged, true);
            app.PathtoexcelfilewhichwillsavedataEditField.Placeholder = 'If this is left blank will be set the same as MRI image input folder';
            app.CheckboxtosavevolumedatatoexcelfileCheckBox = uicheckbox(app.UIFigure);
            app.CheckboxtosavevolumedatatoexcelfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckboxtosavevolumedatatoexcelfileCheckBoxValueChanged, true);
            app.CheckboxtosavevolumedatatoexcelfileCheckBox.Text = 'Check box to save volume data to excel file ';
            app.CheckBoxtosavegrowthtensordatatoexcelfile = uicheckbox(app.UIFigure);
            app.CheckBoxtosavegrowthtensordatatoexcelfile.ValueChangedFcn = createCallbackFcn(app, @CheckBoxtosavegrowthtensordatatoexcelfileValueChanged, true);
            app.CheckBoxtosavegrowthtensordatatoexcelfile.Text = 'Check Box to save growth tensor data to excel file';
            app.FibresformeshButton = uibutton(app.UIFigure, 'push');
            app.FibresformeshButton.ButtonPushedFcn = createCallbackFcn(app, @FibresformeshButtonPushed, true);
            app.FibresformeshButton.Text = 'Fibres for mesh';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'info';
            app.infoButton_2 = uibutton(app.UIFigure, 'push');
            app.infoButton_2.ButtonPushedFcn = createCallbackFcn(app, @infoButton_2Pushed, true);
            app.infoButton_2.Text = 'info';
            app.infoButton_3 = uibutton(app.UIFigure, 'push');
            app.infoButton_3.ButtonPushedFcn = createCallbackFcn(app, @infoButton_3Pushed, true);
            app.infoButton_3.Text = 'info';
            app.infoButton_4 = uibutton(app.UIFigure, 'push');
            app.infoButton_4.ButtonPushedFcn = createCallbackFcn(app, @infoButton_4Pushed, true);
            app.infoButton_4.Text = 'info';
            app.infoButton_5 = uibutton(app.UIFigure, 'push');
            app.infoButton_5.ButtonPushedFcn = createCallbackFcn(app, @infoButton_5Pushed, true);
            app.infoButton_5.Text = 'info';
            app.infoButton_6 = uibutton(app.UIFigure, 'push');
            app.infoButton_6.ButtonPushedFcn = createCallbackFcn(app, @infoButton_6Pushed, true);
            app.infoButton_6.Text = 'info';
            app.infoButton_7 = uibutton(app.UIFigure, 'push');
            app.infoButton_7.ButtonPushedFcn = createCallbackFcn(app, @infoButton_7Pushed, true);
            app.infoButton_7.Text = 'info';
            app.infoButton_8 = uibutton(app.UIFigure, 'push');
            app.infoButton_8.ButtonPushedFcn = createCallbackFcn(app, @infoButton_8Pushed, true);
            app.infoButton_8.Text = 'info';
            app.infoButton_9 = uibutton(app.UIFigure, 'push');
            app.infoButton_9.ButtonPushedFcn = createCallbackFcn(app, @infoButton_9Pushed, true);
            app.infoButton_9.Text = 'info';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '1';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '2';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '3';
            app.SETPATIENTINFORMATIONLabel = uilabel(app.UIFigure);
            app.SETPATIENTINFORMATIONLabel.Text = 'SET PATIENT INFORMATION';
            app.SETFOLDERREQUIREMENTSLabel = uilabel(app.UIFigure);
            app.SETFOLDERREQUIREMENTSLabel.Text = 'SET FOLDER REQUIREMENTS';
            app.infoButton_10 = uibutton(app.UIFigure, 'push');
            app.infoButton_10.ButtonPushedFcn = createCallbackFcn(app, @infoButton_10Pushed, true);
            app.infoButton_10.Text = 'info';
            app.infoButton_11 = uibutton(app.UIFigure, 'push');
            app.infoButton_11.ButtonPushedFcn = createCallbackFcn(app, @infoButton_11Pushed, true);
            app.infoButton_11.Text = 'info';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Placeholder = 'e.g. empa';
            app.MaxPatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.MaxPatientIDEditFieldLabel.Text = 'Max Patient ID';
            app.MaxPatientIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxPatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPatientIDEditFieldValueChanged, true);
            app.NameofpatientstudyLabel = uilabel(app.UIFigure);
            app.NameofpatientstudyLabel.Text = 'Name of patient study ';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end